classdef Robotics_Samer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        RobotDescriptionTab         matlab.ui.container.Tab
        VisualizeButton             matlab.ui.control.Button
        NumberofJointsSpinner       matlab.ui.control.Spinner
        NumberofJointsSpinnerLabel  matlab.ui.control.Label
        LockCheckBox                matlab.ui.control.CheckBox
        SubmitButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        ForwardKinematicsTab        matlab.ui.container.Tab
        WithoutPeterCorkeLabel      matlab.ui.control.Label
        WithPeterCorkeLabel         matlab.ui.control.Label
        YawEditField_2              matlab.ui.control.NumericEditField
        YawEditField_2Label         matlab.ui.control.Label
        PitchEditField_2            matlab.ui.control.NumericEditField
        PitchEditField_2Label       matlab.ui.control.Label
        RollEditField_2             matlab.ui.control.NumericEditField
        RollEditField_2Label        matlab.ui.control.Label
        ZEditField_2                matlab.ui.control.NumericEditField
        ZEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.NumericEditField
        XEditField_2Label           matlab.ui.control.Label
        ForwardKinematicsButton     matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        YawEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel           matlab.ui.control.Label
        PitchEditField              matlab.ui.control.NumericEditField
        PitchEditFieldLabel         matlab.ui.control.Label
        RollEditField               matlab.ui.control.NumericEditField
        RollEditFieldLabel          matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        InverseKinematicsTab        matlab.ui.container.Tab
        WorkspaceTab                matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function  extracted_information = extract(app,data)
            type   = data(:,1);
            theta  = data(:,2);
            d      = data(:,3);
            a      = data(:,4);
            alpha  = data(:,5);
            qlim_1 = data(:,6);
            qlim_2 = data(:,7);
            extracted_information = [theta d a alpha type qlim_1 qlim_2];
            return;
                
        end

        function robot = create_robot(app,data)
            global rows
            Links = Link();
            for index= 1:rows

                Links(1,index) = Link(data(index,1:5));
                Links(1,index).qlim = data(index,6:7);
            end
            robot = SerialLink(Links,'name','robot');
            return

        end

        function endeffector = forward_kinematics(app,data,q)
            global rows
            endeffector = eye(4);
            dh_parameters = data(:,1:4);

            for index=1:rows
                if data(index,5) == 0
                    dh_parameters(index,1) = q(index);
                else
                    dh_parameters(index,3) = q(index);
                end
            end
            disp(dh_parameters);
            for index=1:rows
                theta = dh_parameters(index,1);
                d     = dh_parameters(index,2);
                a     = dh_parameters(index,3);
                alpha = dh_parameters(index,4);
                transformation = [cos(theta) -sin(theta)*cos(alpha) sin(theta)*sin(alpha) a*cos(theta);
                                  sin(theta) cos(theta)*cos(alpha) -cos(theta)*sin(alpha) a*sin(theta);
                                   0 sin(alpha) cos(alpha) d;
                                   0 0 0 1];
                endeffector = endeffector * transformation;
            end
            return

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.RowName  = {'Joint1';'Joint2';'Joint3';'Joint4';'Joint5';'Joint6';'Joint7';'Joint8'};
            app.UITable2.RowName = {'Joint1';'Joint2';'Joint3';'Joint4';'Joint5';'Joint6';'Joint7';'Joint8'};
        end

        % Value changed function: LockCheckBox
        function LockCheckBoxValueChanged(app, event)
            global rows
            value = app.LockCheckBox.Value;
            if value
                app.NumberofJointsSpinner.Enable = "off";
                app.SubmitButton.Enable = "on";
            else
                app.NumberofJointsSpinner.Enable = "on";
                app.SubmitButton.Enable = "off";
            end
            rows = app.NumberofJointsSpinner.Value;
            columns = 7;
            size = [rows columns];
            varTypes = strings(1,columns);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data=data;

            columns = 1;
            size = [rows columns];
            varTypes = strings(1,columns);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable2.RowName = {'Joint1';'Joint2';'Joint3';'Joint4';'Joint5';'Joint6';'Joint7';'Joint8'};
            app.UITable2.Data=data;


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            global new_data; 
            global rows;
            global robot
            data = table2array(app.UITable.Data);
            app.VisualizeButton.Enable = "on";

            new_data=extract(app,data);
            % disp(new_data)
            robot = create_robot(app,new_data);
            % disp(robot);
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            global rows
            global robot
            robot.plot(zeros(1,rows));          
               
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            global robot
            global new_data
            q = table2array(app.UITable2.Data);
            End_Effector = forward_kinematics(app,new_data,q);
            disp(new_data);
            % disp(End_Effector);
            app.XEditField.Value     = End_Effector(1,4);
            app.YEditField.Value     = End_Effector(2,4);
            app.ZEditField.Value     = End_Effector(3,4);
            
            Roll  = atan2(End_Effector(3,2),End_Effector(3,3));
            Pitch = atan2(-End_Effector(3,1),sqrt(End_Effector(3,2).^2+End_Effector(3,3).^2));
            Yaw   = atan2(End_Effector(2,1),End_Effector(1,1));
            app.RollEditField.Value  = Roll;
            app.PitchEditField.Value = Pitch;
            app.YawEditField.Value   = Yaw; 
            
            End_Effector_Peter_Corke=double(robot.fkine(q.'));
            % disp(End_Effector_Peter_Corke);
            app.XEditField_2.Value     = End_Effector_Peter_Corke(1,4);
            app.YEditField_2.Value     = End_Effector_Peter_Corke(2,4);
            app.ZEditField_2.Value     = End_Effector_Peter_Corke(3,4);
            
            Roll  = atan2(End_Effector_Peter_Corke(3,2),End_Effector_Peter_Corke(3,3));
            Pitch = atan2(-End_Effector_Peter_Corke(3,1),sqrt(End_Effector_Peter_Corke(3,2).^2+End_Effector_Peter_Corke(3,3).^2));
            Yaw   = atan2(End_Effector_Peter_Corke(2,1),End_Effector_Peter_Corke(1,1));
            app.RollEditField_2.Value  = Roll;
            app.PitchEditField_2.Value = Pitch;
            app.YawEditField_2.Value   = Yaw;  

            robot.plot(q.')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RobotDescriptionTab = uitab(app.TabGroup);
            app.RobotDescriptionTab.Title = 'Robot Description';
            app.UITable = uitable(app.RobotDescriptionTab);
            app.UITable.ColumnName = {'Joint Type'; 'theta(degrees)'; 'd(meter)'; 'a(meter)'; 'alpha(degree)'; 'minimum limit'; 'maximum limit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SubmitButton = uibutton(app.RobotDescriptionTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.LockCheckBox = uicheckbox(app.RobotDescriptionTab);
            app.LockCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockCheckBoxValueChanged, true);
            app.LockCheckBox.Text = 'Lock';
            app.NumberofJointsSpinnerLabel = uilabel(app.RobotDescriptionTab);
            app.NumberofJointsSpinnerLabel.Text = 'Number of Joints';
            app.NumberofJointsSpinner = uispinner(app.RobotDescriptionTab);
            app.NumberofJointsSpinner.Limits = [0 8];
            app.VisualizeButton = uibutton(app.RobotDescriptionTab, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.XEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.RollEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.UITable2 = uitable(app.ForwardKinematicsTab);
            app.UITable2.ColumnName = {'Q'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ForwardKinematicsButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.XEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.YEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.ZEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.RollEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.ForwardKinematicsTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.WithPeterCorkeLabel = uilabel(app.ForwardKinematicsTab);
            app.WithPeterCorkeLabel.Text = 'With Peter Corke';
            app.WithoutPeterCorkeLabel = uilabel(app.ForwardKinematicsTab);
            app.WithoutPeterCorkeLabel.Text = 'Without Peter Corke';
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robotics_Samer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end