classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        InverseButton    matlab.ui.control.Button
        ForwardButton    matlab.ui.control.Button
        ZEditField       matlab.ui.control.NumericEditField
        ZEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.NumericEditField
        XEditFieldLabel  matlab.ui.control.Label
        EditField_3      matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        EditField_2      matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            Th_1 = app.EditField.Value   * pi / 180;
            Th_2 = app.EditField_2.Value * pi / 180;
            Th_3 = app.EditField_3.Value * pi / 180;
        
            L_1 = 20;
            L_2 = 50;
            L_3 = 40;
        
            
            L(1) = Link([0, L_1, 0, pi/2]);
            L(2) = Link([0, 0, L_2, 0]);
            L(3) = Link([0, 0, L_3, 0]);
        
            Robot = SerialLink(L);
            Robot.name = 'ARM';
        
            Robot.plot([Th_1, Th_2, Th_3]);
        
            T = Robot.fkine([Th_1, Th_2, Th_3]);
        
            app.XEditField.Value = T.t(1); 
            app.YEditField.Value = T.t(2);
            app.ZEditField.Value = T.t(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'θ1';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'θ2';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'θ3';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.Text = 'Inverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end