classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DOFRRRPanel              matlab.ui.container.Panel
        Slider_3                 matlab.ui.control.Slider
        Slider_2                 matlab.ui.control.Slider
        Slider                   matlab.ui.control.Slider
        Panel                    matlab.ui.container.Panel
        L3EditField              matlab.ui.control.NumericEditField
        L3EditFieldLabel         matlab.ui.control.Label
        L2EditField              matlab.ui.control.NumericEditField
        L2EditFieldLabel         matlab.ui.control.Label
        L1EditField              matlab.ui.control.NumericEditField
        L1EditFieldLabel         matlab.ui.control.Label
        WorkingSpaceSwitch       matlab.ui.control.Switch
        WorkingSpaceSwitchLabel  matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        ZEditField               matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        degEditField_3           matlab.ui.control.NumericEditField
        degEditField_3Label      matlab.ui.control.Label
        degEditField_2           matlab.ui.control.NumericEditField
        degEditField_2Label      matlab.ui.control.Label
        degEditField             matlab.ui.control.NumericEditField
        degLabel                 matlab.ui.control.Label
        InverseButton            matlab.ui.control.Button
        ForwardButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = 'r';
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            Th_1 = app.degEditField.Value   * pi / 180;
            Th_2 = app.degEditField_2.Value * pi / 180;
            Th_3 = app.degEditField_3.Value * pi / 180;
        
            L_1 = app.L1EditField.Value;
            L_2 = app.L2EditField.Value;
            L_3 = app.L3EditField.Value;
        
            
            L(1) = Link([0, L_1, 0, pi/2]);
            L(2) = Link([0, 0, L_2, 0]);
            L(3) = Link([0, 0, L_3, 0]);
        
            Robot = SerialLink(L);
            Robot.name = 'ARM';
        
            Robot.plot([Th_1, Th_2, Th_3]);
        
            T = Robot.fkine([Th_1, Th_2, Th_3]);
        
            app.XEditField.Value = T.t(1); 
            app.YEditField.Value = T.t(2);
            app.ZEditField.Value = T.t(3);
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            PX = app.XEditField.Value;
            PY = app.YEditField.Value;
            PZ = app.ZEditField.Value;
            
            L_1 = app.L1EditField.Value;
            L_2 = app.L2EditField.Value;
            L_3 = app.L3EditField.Value;
            
            L(1) = Link([0 L_1 0 pi/2]);
            L(2) = Link([0 0 L_2 0]);
            L(3) = Link([0 0 L_3 0]);
            
            Robot = SerialLink(L);
            Robot.name = 'ARM';
            
            T = [ 1 0 0 PX;
                  0 1 0 PY;
                  0 0 1 PZ;
                  0 0 0 1];
              
            J = Robot.ikine(T, 'mask', [1 1 1 0 0 0]);

            J_deg = rad2deg(J);
            
            app.degEditField.Value = J_deg(1,1);
            app.degEditField_2.Value = J_deg(1,2);
            app.degEditField_3.Value = J_deg(1,3);
            
            Robot.plot(J);
        end

        % Value changed function: WorkingSpaceSwitch
        function WorkingSpaceSwitchValueChanged(app, event)
            value = app.WorkingSpaceSwitch.Value;
            if strcmp(value,'On')
                app.Lamp.Color = 'g';
%                 for q1=0.0: (pi/8): (2*pi)
%                     for q2=0.0: (pi/8): (2*pi)
%                         Pr =trchain2('R(0) Tx(20) R(0) Tx(50)', [ql q2]);
%                         RB.plot([ql q2])
%                         x=Pr (1,3);
%                         y=Pr (2,3);
%                         P=[x,y]';
%                         plot_point(P,'*');
%                     end
%                 end
            else 
                app.Lamp.Color = 'r';
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.degEditField.Value = value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.degEditField_2.Value = value;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            app.degEditField_3.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DOFRRRPanel = uipanel(app.UIFigure);
            app.DOFRRRPanel.Title = '3 D O F (RRR)';
            app.ForwardButton = uibutton(app.DOFRRRPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.DOFRRRPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.degLabel = uilabel(app.DOFRRRPanel);
            app.degLabel.Text = 'θ1 (deg)';
            app.degEditField = uieditfield(app.DOFRRRPanel, 'numeric');
            app.degEditField_2Label = uilabel(app.DOFRRRPanel);
            app.degEditField_2Label.Text = 'θ2 (deg)';
            app.degEditField_2 = uieditfield(app.DOFRRRPanel, 'numeric');
            app.degEditField_3Label = uilabel(app.DOFRRRPanel);
            app.degEditField_3Label.Text = 'θ3 (deg)';
            app.degEditField_3 = uieditfield(app.DOFRRRPanel, 'numeric');
            app.XEditFieldLabel = uilabel(app.DOFRRRPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.DOFRRRPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.DOFRRRPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.DOFRRRPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DOFRRRPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.DOFRRRPanel, 'numeric');
            app.Lamp = uilamp(app.DOFRRRPanel);
            app.WorkingSpaceSwitchLabel = uilabel(app.DOFRRRPanel);
            app.WorkingSpaceSwitchLabel.Text = {'Working'; 'Space'};
            app.WorkingSpaceSwitch = uiswitch(app.DOFRRRPanel, 'slider');
            app.WorkingSpaceSwitch.ValueChangedFcn = createCallbackFcn(app, @WorkingSpaceSwitchValueChanged, true);
            app.Panel = uipanel(app.DOFRRRPanel);
            app.L1EditFieldLabel = uilabel(app.Panel);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.Panel, 'numeric');
            app.L2EditFieldLabel = uilabel(app.Panel);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.Panel, 'numeric');
            app.L3EditFieldLabel = uilabel(app.Panel);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.Panel, 'numeric');
            app.Slider = uislider(app.DOFRRRPanel);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.DOFRRRPanel);
            app.Slider_2.Limits = [0 360];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.DOFRRRPanel);
            app.Slider_3.Limits = [0 360];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end