classdef app_2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditField_10                 matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        Joint10Slider                matlab.ui.control.Slider
        Joint10SliderLabel           matlab.ui.control.Label
        Joint9Slider                 matlab.ui.control.Slider
        Joint9SliderLabel            matlab.ui.control.Label
        Joint8Slider                 matlab.ui.control.Slider
        Joint8SliderLabel            matlab.ui.control.Label
        Joint7Slider                 matlab.ui.control.Slider
        Joint7SliderLabel            matlab.ui.control.Label
        Joint6Slider                 matlab.ui.control.Slider
        Joint6SliderLabel            matlab.ui.control.Label
        Joint5Slider                 matlab.ui.control.Slider
        Joint5SliderLabel            matlab.ui.control.Label
        Joint4Slider                 matlab.ui.control.Slider
        Joint4SliderLabel            matlab.ui.control.Label
        Joint3Slider                 matlab.ui.control.Slider
        Joint3SliderLabel            matlab.ui.control.Label
        Joint2Slider                 matlab.ui.control.Slider
        Joint2SliderLabel            matlab.ui.control.Label
        Joint1Slider                 matlab.ui.control.Slider
        Joint1SliderLabel            matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        EntranceTab                  matlab.ui.container.Tab
        YaxislimitsEditFieldLabel_4  matlab.ui.control.Label
        ZaxislimitsEditField         matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        ZaxislimitsEditField_2       matlab.ui.control.NumericEditField
        YaxislimitsEditFieldLabel_3  matlab.ui.control.Label
        YaxislimitsEditField         matlab.ui.control.NumericEditField
        Label_4                      matlab.ui.control.Label
        YaxislimitsEditField_2       matlab.ui.control.NumericEditField
        YaxislimitsEditFieldLabel_2  matlab.ui.control.Label
        XaxislimitsEditField         matlab.ui.control.NumericEditField
        XaxislimitsEditField_2       matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        PLotRobotButton              matlab.ui.control.Button
        CreateDHTableButton          matlab.ui.control.StateButton
        DOFSpinner                   matlab.ui.control.Spinner
        DOFLabel                     matlab.ui.control.Label
        RobotNameEditFieldLabel      matlab.ui.control.Label
        RobotNameEditField           matlab.ui.control.EditField
        EnableCheckBox               matlab.ui.control.CheckBox
        SubmitParametersButton       matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        KinematicsTab                matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        InverseKinematicsPanel       matlab.ui.container.Panel
        ZEditField_3                 matlab.ui.control.NumericEditField
        ZEditField_3Label            matlab.ui.control.Label
        YEditField_3                 matlab.ui.control.NumericEditField
        YEditField_3Label            matlab.ui.control.Label
        XEditField_3                 matlab.ui.control.NumericEditField
        XEditField_3Label            matlab.ui.control.Label
        SolveIKButton                matlab.ui.control.Button
        PeterCorkePanel              matlab.ui.container.Panel
        ZEditField_2                 matlab.ui.control.NumericEditField
        ZEditField_2Label            matlab.ui.control.Label
        YEditField_2                 matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        XEditField_2                 matlab.ui.control.NumericEditField
        XEditField_2Label            matlab.ui.control.Label
        ZEditField_4                 matlab.ui.control.NumericEditField
        ZEditField_4Label            matlab.ui.control.Label
        YEditField_4                 matlab.ui.control.NumericEditField
        YEditField_4Label            matlab.ui.control.Label
        XEditField_4                 matlab.ui.control.NumericEditField
        XEditField_4Label            matlab.ui.control.Label
        SolveIKButton_2              matlab.ui.control.Button
        SolveFKButton_2              matlab.ui.control.Button
        ForwardKinematicsPanel       matlab.ui.container.Panel
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditField_5Label            matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditField_5Label            matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditField_5Label            matlab.ui.control.Label
        SolveFKButton                matlab.ui.control.Button
        EnvelopeTab                  matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        WorkingSpaceSwitch           matlab.ui.control.Switch
        SwitchLabel                  matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        GuideTab                     matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function Base_Plot(app)
            X_min = app.XaxislimitsEditField.Value;
            X_max = app.XaxislimitsEditField_2.Value;
            Y_min = app.YaxislimitsEditField.Value;
            Y_max = app.YaxislimitsEditField_2.Value;
            Z_min = app.ZaxislimitsEditField.Value;
            Z_max = app.ZaxislimitsEditField_2.Value;
            app.UIAxes.XLim = [X_min X_max];
            app.UIAxes.YLim = [Y_min Y_max];
            app.UIAxes.ZLim = [Z_min Z_max];
            
            cla(app.UIAxes);
            plot3(app.UIAxes,0,0,0,'k^','LineWidth',3,'MarkerFaceColor','k');
            if app.EnableCheckBox.Value
                app.UIAxes.Title.String = app.RobotNameEditField.Value;  
            else
                app.UIAxes.Title.String = 'ARM';               
            end

            
            dh_parameters = table2array(app.UITable.Data(:,1:4));
            
            th = dh_parameters(1,1);
            d     = dh_parameters(1,2);
            a     = dh_parameters(1,3);
            alph = dh_parameters(1,4);
            T1 = [ cosd(th) -cosd(alph)*sind(th) sind(alph)*sind(th) a*cosd(th)
                  sind(th) cosd(alph)*cosd(th) -sind(alph)*cosd(th) a*sind(th)
                  0       sind(alph)          cosd(alph)         d
                  0       0                   0                  1];
                            
            line(app.UIAxes,[0 T1(1,4)], [0 T1(2,4)], [0 T1(3,4)], 'Color', 'y', 'LineWidth', 2);
            hold(app.UIAxes , 'on');
            scatter3(app.UIAxes,T1(1,4), T1(2,4), T1(3,4), 'ro', 'LineWidth', 3, 'MarkerFaceColor','r');
        end
        
        
        function Peter_FK(app,L)
            X_min = app.XaxislimitsEditField.Value;
            X_max = app.XaxislimitsEditField_2.Value;
            Y_min = app.YaxislimitsEditField.Value;
            Y_max = app.YaxislimitsEditField_2.Value;
            Z_min = app.ZaxislimitsEditField.Value;
            Z_max = app.ZaxislimitsEditField_2.Value;
            
            L(:,4) = L(:,4) * pi/180;
            theta = L(:,1)*pi/180;
            
            Robot = SerialLink(L);
            
            if app.EnableCheckBox.Value
                Robot.name = app.RobotNameEditField.Value;  
            else
                Robot.name = 'ARM';                
            end
            
            
            Robot.plot(theta','workspace',[X_min X_max Y_min Y_max Z_min Z_max]);
        
            T = Robot.fkine(theta');
        
            app.XEditField_2.Value = T.t(1); 
            app.YEditField_2.Value = T.t(2);
            app.ZEditField_2.Value = T.t(3);
        end
      
        
        function My_FK(app,L)
            rows = app.DOFSpinner.Value;
            endeffector = eye(4);
            dh_parameters = L(:,1:4);
            disp(dh_parameters);
            for index=1:rows
                th = dh_parameters(index,1);
                d     = dh_parameters(index,2);
                a     = dh_parameters(index,3);
                alph = dh_parameters(index,4);
                A = [ cosd(th) -cosd(alph)*sind(th) sind(alph)*sind(th) a*cosd(th)
                      sind(th) cosd(alph)*cosd(th) -sind(alph)*cosd(th) a*sind(th)
                      0       sind(alph)          cosd(alph)         d
                      0       0                   0                  1];
                endeffector = endeffector * A;
                
                T3 = endeffector;
                if index~=1                  
                    if mod(index, 2) == 0
                        line(app.UIAxes,[T2(1,4) T3(1,4)], [T2(2,4) T3(2,4)], [T2(3,4) T3(3,4)],  'Color', 'c', 'LineWidth',2);
                        hold(app.UIAxes , 'on');
                        scatter3(app.UIAxes,T3(1,4), T3(2,4), T3(3,4), 'bo', 'LineWidth', 3, 'MarkerFaceColor', 'b');

                    else
                        line(app.UIAxes,[T2(1,4) T3(1,4)], [T2(2,4) T3(2,4)], [T2(3,4) T3(3,4)], 'Color', 'm', 'LineWidth',2);
                        hold(app.UIAxes , 'on');
                        scatter3(app.UIAxes,T3(1,4), T3(2,4), T3(3,4), 'go', 'LineWidth', 3, 'MarkerFaceColor','g');
                    end
                end
                T2 = endeffector;
                
            end
            disp(endeffector);
            app.XEditField.Value = endeffector(1,4); 
            app.YEditField.Value = endeffector(2,4);
            app.ZEditField.Value = endeffector(3,4);
            return
        end
        
        
        function Peter_IK(app,L)
            X_min = app.XaxislimitsEditField.Value;
            X_max = app.XaxislimitsEditField_2.Value;
            Y_min = app.YaxislimitsEditField.Value;
            Y_max = app.YaxislimitsEditField_2.Value;
            Z_min = app.ZaxislimitsEditField.Value;
            Z_max = app.ZaxislimitsEditField_2.Value;
            
            L(:,4) = L(:,4) * pi/180;
            L(:,1) = L(:,1)*pi/180;
            
            Robot = SerialLink(L);
            
            if app.EnableCheckBox.Value
                Robot.name = app.RobotNameEditField.Value;  
            else
                Robot.name = 'ARM';                
            end
            
            PX = app.XEditField_4.Value;
            PY = app.YEditField_4.Value;
            PZ = app.ZEditField_4.Value;
            
            
            T = [ 1 0 0 PX;
                0 1 0 PY;
                0 0 1 PZ;
                0 0 0 1];
    
            J = Robot.ikine(T, 'mask', [1 1 1 0 0 0]);

            J_deg = rad2deg(J);

%             % Display the joint angles
%             disp('Joint angles in degrees:');
%             disp(q_deg(1,1));
%             disp(q_deg(1,2));
%             disp(q_deg(1,3));
            
            Robot.plot(J,'workspace',[X_min X_max Y_min Y_max Z_min Z_max]);
            
            
            if app.DOFSpinner.Value >= 1
                app.EditField.Value = J_deg(1,1);
            end
            
            if app.DOFSpinner.Value >= 2
                app.EditField_2.Value = J_deg(1,2);
            end
            
            if app.DOFSpinner.Value >= 3
                app.EditField_3.Value = J_deg(1,3);
            end
            
            if app.DOFSpinner.Value >= 4
                app.EditField_4.Value = J_deg(1,4);
            end
            
            if app.DOFSpinner.Value >= 5
                app.EditField_5.Value = J_deg(1,5);
            end
            
            if app.DOFSpinner.Value >= 6
                app.EditField_6.Value = J_deg(1,6);
            end
            
            if app.DOFSpinner.Value >= 7
                app.EditField_7.Value = J_deg(1,7);
            end
            
            if app.DOFSpinner.Value >= 8
                app.EditField_8.Value = J_deg(1,8);
            end
            
            if app.DOFSpinner.Value >= 9
                app.EditField_9.Value = J_deg(1,9);
            end
            
            if app.DOFSpinner.Value >= 10
                app.EditField_10.Value = J_deg(1,10);
            end
            
            
        end
        
        
        function My_IK(app,L)
            
            X_min = app.XaxislimitsEditField.Value;
            X_max = app.XaxislimitsEditField_2.Value;
            Y_min = app.YaxislimitsEditField.Value;
            Y_max = app.YaxislimitsEditField_2.Value;
            Z_min = app.ZaxislimitsEditField.Value;
            Z_max = app.ZaxislimitsEditField_2.Value;
            
            L(:,4) = L(:,4) * pi/180;
            L(:,1) = L(:,1)*pi/180;
            
            Robot = SerialLink(L);
            
            if app.EnableCheckBox.Value
                Robot.name = app.RobotNameEditField.Value;  
            else
                Robot.name = 'ARM';                
            end
            
            PX = app.XEditField_3.Value;
            PY = app.YEditField_3.Value;
            PZ = app.ZEditField_3.Value;
            
            
            T = [ 1 0 0 PX;
                0 1 0 PY;
                0 0 1 PZ;
                0 0 0 1];
    
            J = Robot.ikine(T, 'mask', [1 1 1 0 0 0]);

            J_deg = rad2deg(J);
            
            Robot.plot(J,'workspace',[X_min X_max Y_min Y_max Z_min Z_max]);
            
            
            if app.DOFSpinner.Value >= 1
                app.EditField.Value = J_deg(1,1);
            end
            
            if app.DOFSpinner.Value >= 2
                app.EditField_2.Value = J_deg(1,2);
            end
            
            if app.DOFSpinner.Value >= 3
                app.EditField_3.Value = J_deg(1,3);
            end
            
            if app.DOFSpinner.Value >= 4
                app.EditField_4.Value = J_deg(1,4);
            end
            
            if app.DOFSpinner.Value >= 5
                app.EditField_5.Value = J_deg(1,5);
            end
            
            if app.DOFSpinner.Value >= 6
                app.EditField_6.Value = J_deg(1,6);
            end
            
            if app.DOFSpinner.Value >= 7
                app.EditField_7.Value = J_deg(1,7);
            end
            
            if app.DOFSpinner.Value >= 8
                app.EditField_8.Value = J_deg(1,8);
            end
            
            if app.DOFSpinner.Value >= 9
                app.EditField_9.Value = J_deg(1,9);
            end
            
            if app.DOFSpinner.Value >= 10
                app.EditField_10.Value = J_deg(1,10);
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = 'r';
        end

        % Button pushed function: PLotRobotButton
        function PLotRobotButtonPushed(app, event)
            app.UIAxes.Visible = "on";
            Base_Plot(app)    
            L = table2array(app.UITable.Data(:,1:8));
            My_FK(app,L)
        end

        % Value changed function: CreateDHTableButton
        function CreateDHTableButtonValueChanged(app, event)
            global rows
            value = app.CreateDHTableButton.Value;
            if value 
                app.UITable.Enable = "on";
                app.DOFSpinner.Enable = "off";
                app.SubmitParametersButton.Enable = "on";
                app.PLotRobotButton.Enable = "on";
            else
                app.UITable.Enable = "off";
                app.DOFSpinner.Enable = "on";
                app.SubmitParametersButton.Enable = "off";
                app.PLotRobotButton.Enable = "off";
                app.Joint1Slider.Enable = 'off';
                app.EditField.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.EditField_2.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.EditField_3.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.EditField_4.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.EditField_5.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.EditField_6.Enable = 'off';
                app.Joint7Slider.Enable = 'off';
                app.EditField_7.Enable = 'off';
                app.Joint8Slider.Enable = 'off';
                app.EditField_8.Enable = 'off';
                app.Joint9Slider.Enable = 'off';
                app.EditField_9.Enable = 'off';
                app.Joint10Slider.Enable = 'off';
                app.EditField_10.Enable = 'off';
            end
            rows = app.DOFSpinner.Value;  
            app.UITable.RowName  = {'Joint1';'Joint2';'Joint3';'Joint4';'Joint5';'Joint6';'Joint7';'Joint8';'Joint9';'Joint10'};
            columns = 8;
            size = [rows columns];
            varTypes = strings(1,columns);
            varTypes = varTypes+'double';
            data = table('Size',size,'VariableTypes',varTypes);
            app.UITable.Data=data;
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            value = app.EnableCheckBox.Value;
            if value
                app.RobotNameEditField.Enable = "on";  
            else
                app.RobotNameEditField.Enable = "off";                
            end
        end

        % Button pushed function: SubmitParametersButton
        function SubmitParametersButtonPushed(app, event)
%             global new_data; 
%             global rows;
%             global robot
%             data = table2array(app.UITable.Data);
%             new_data=extract(app,data);
%             robot = create_robot(app,new_data);
                        
            if app.DOFSpinner.Value >= 1
                app.Joint1Slider.Enable = 'on';
                app.EditField.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 2
                app.Joint2Slider.Enable = 'on';
                app.EditField_2.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 3
                app.Joint3Slider.Enable = 'on';
                app.EditField_3.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 4
                app.Joint4Slider.Enable = 'on';
                app.EditField_4.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 5
                app.Joint5Slider.Enable = 'on';
                app.EditField_5.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 6
                app.Joint6Slider.Enable = 'on';
                app.EditField_6.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 7
                app.Joint7Slider.Enable = 'on';
                app.EditField_7.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 8
                app.Joint8Slider.Enable = 'on';
                app.EditField_8.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 9
                app.Joint9Slider.Enable = 'on';
                app.EditField_9.Enable = 'on';
            end
            
            if app.DOFSpinner.Value >= 10
                app.Joint10Slider.Enable = 'on';
                app.EditField_10.Enable = 'on';
            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(1,5) == 1
                L(1,6) = changingValue;
            else
                L(1,1) = changingValue;
            end
            
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(2,5) == 1
                L(2,6) = changingValue;
            else
                L(2,1) = changingValue;
            end
            
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(3,5) == 1
                L(3,6) = changingValue;
            else
                L(3,1) = changingValue;
            end
            
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(4,5) == 1
                L(4,6) = changingValue;
            else
                L(4,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(5,5) == 1
                L(5,6) = changingValue;
            else
                L(5,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(6,5) == 1
                L(6,6) = changingValue;
            else
                L(6,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint7Slider
        function Joint7SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_7.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(7,5) == 1
                L(7,6) = changingValue;
            else
                L(7,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint8Slider
        function Joint8SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_8.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(8,5) == 1
                L(8,6) = changingValue;
            else
                L(8,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint9Slider
        function Joint9SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_9.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(9,5) == 1
                L(9,6) = changingValue;
            else
                L(9,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: Joint10Slider
        function Joint10SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_10.Value = changingValue;
            
            L = table2array(app.UITable.Data(:,1:6));
            if L(10,5) == 1
                L(10,6) = changingValue;
            else
                L(10,1) = changingValue;
            end
            Peter_FK(app,L)
            Base_Plot(app)
            My_FK(app,L)
        end

        % Value changing function: RobotNameEditField
        function RobotNameEditFieldValueChanging(app, event)

        end

        % Button pushed function: SolveFKButton
        function SolveFKButtonPushed(app, event)
            L = table2array(app.UITable.Data(:,1:6));
            My_FK(app,L)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Joint1Slider.Value = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Joint2Slider.Value = value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Joint13lider.Value = value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Joint4Slider.Value = value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Joint5Slider.Value = value;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Joint6Slider.Value = value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.Joint7Slider.Value = value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.Joint8Slider.Value = value;
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.Joint9Slider.Value = value;
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.Joint10Slider.Value = value;
        end

        % Button pushed function: SolveFKButton_2
        function SolveFKButton_2Pushed(app, event)
            L = table2array(app.UITable.Data(:,1:6));
            Peter_FK(app,L)
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)

        end

        % Callback function
        function ClearButtonPushed(app, event)
           
        end

        % Button pushed function: SolveIKButton_2
        function SolveIKButton_2Pushed(app, event)
            L = table2array(app.UITable.Data(:,1:6));
            Peter_IK(app,L)
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            
        end

        % Value changed function: WorkingSpaceSwitch
        function WorkingSpaceSwitchValueChanged(app, event)
            value = app.WorkingSpaceSwitch.Value;
            joints = app.DOFSpinner.Value;
            if strcmp(value,'On')
                app.Lamp.Color = 'g';
                L = table2array(app.UITable.Data(:,1:8));
%                 Limitis = L(:,7:8);
                for i=1:1:joints

                    for j=L(i,7):10:L(i,8)
                        
                        L(i,1) = j;
                        My_FK(app,L);

                    end

                end
                
%                 app.UIAxes2 = view(app.UIAxes, [1 0 0]); % This sets the azimuth to 90 degrees and the elevation to 0 degrees

            else 
                app.Lamp.Color = 'r';
                Base_Plot(app)
                L = table2array(app.UITable.Data(:,1:6));
                Peter_FK(app,L)
            end
        end

        % Button pushed function: SolveIKButton
        function SolveIKButtonPushed(app, event)
            L = table2array(app.UITable.Data(:,1:6));
            My_IK(app,L)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntranceTab = uitab(app.TabGroup);
            app.EntranceTab.Title = 'Entrance';
            app.UITable = uitable(app.EntranceTab);
            app.UITable.ColumnName = {'theta(deg)'; 'd'; 'a'; 'alpha(deg)'; 'Type(0=R / 1=P)'; 'offset'; 'Min_Limit'; 'Max_Limit'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.Enable = 'off';
            app.SubmitParametersButton = uibutton(app.EntranceTab, 'push');
            app.SubmitParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitParametersButtonPushed, true);
            app.SubmitParametersButton.Enable = 'off';
            app.SubmitParametersButton.Text = 'Submit Parameters';
            app.EnableCheckBox = uicheckbox(app.EntranceTab);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.RobotNameEditField = uieditfield(app.EntranceTab, 'text');
            app.RobotNameEditField.ValueChangingFcn = createCallbackFcn(app, @RobotNameEditFieldValueChanging, true);
            app.RobotNameEditField.Enable = 'off';
            app.RobotNameEditFieldLabel = uilabel(app.EntranceTab);
            app.RobotNameEditFieldLabel.Text = 'Robot Name';
            app.DOFLabel = uilabel(app.EntranceTab);
            app.DOFLabel.Text = '# DOF';
            app.DOFSpinner = uispinner(app.EntranceTab);
            app.DOFSpinner.Limits = [0 10];
            app.CreateDHTableButton = uibutton(app.EntranceTab, 'state');
            app.CreateDHTableButton.ValueChangedFcn = createCallbackFcn(app, @CreateDHTableButtonValueChanged, true);
            app.CreateDHTableButton.Text = 'Create  DH Table';
            app.PLotRobotButton = uibutton(app.EntranceTab, 'push');
            app.PLotRobotButton.ButtonPushedFcn = createCallbackFcn(app, @PLotRobotButtonPushed, true);
            app.PLotRobotButton.Enable = 'off';
            app.PLotRobotButton.Text = 'PLot Robot';
            app.Label = uilabel(app.EntranceTab);
            app.Label.Text = ':';
            app.XaxislimitsEditField_2 = uieditfield(app.EntranceTab, 'numeric');
            app.XaxislimitsEditField = uieditfield(app.EntranceTab, 'numeric');
            app.YaxislimitsEditFieldLabel_2 = uilabel(app.EntranceTab);
            app.YaxislimitsEditFieldLabel_2.Text = 'X-axis limits';
            app.YaxislimitsEditField_2 = uieditfield(app.EntranceTab, 'numeric');
            app.Label_4 = uilabel(app.EntranceTab);
            app.Label_4.Text = ':';
            app.YaxislimitsEditField = uieditfield(app.EntranceTab, 'numeric');
            app.YaxislimitsEditFieldLabel_3 = uilabel(app.EntranceTab);
            app.YaxislimitsEditFieldLabel_3.Text = 'Y-axis limits';
            app.ZaxislimitsEditField_2 = uieditfield(app.EntranceTab, 'numeric');
            app.Label_5 = uilabel(app.EntranceTab);
            app.Label_5.Text = ':';
            app.ZaxislimitsEditField = uieditfield(app.EntranceTab, 'numeric');
            app.YaxislimitsEditFieldLabel_4 = uilabel(app.EntranceTab);
            app.YaxislimitsEditFieldLabel_4.Text = 'Z-axis limits';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.GridLayout = uigridlayout(app.KinematicsTab);
            app.ForwardKinematicsPanel = uipanel(app.GridLayout);
            app.ForwardKinematicsPanel.Title = 'Forward Kinematics';
            app.SolveFKButton = uibutton(app.ForwardKinematicsPanel, 'push');
            app.SolveFKButton.ButtonPushedFcn = createCallbackFcn(app, @SolveFKButtonPushed, true);
            app.SolveFKButton.Text = 'Solve FK';
            app.XEditField_5Label = uilabel(app.ForwardKinematicsPanel);
            app.XEditField_5Label.Text = 'X';
            app.XEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.YEditField_5Label = uilabel(app.ForwardKinematicsPanel);
            app.YEditField_5Label.Text = 'Y';
            app.YEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.ZEditField_5Label = uilabel(app.ForwardKinematicsPanel);
            app.ZEditField_5Label.Text = 'Z';
            app.ZEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.PeterCorkePanel = uipanel(app.GridLayout);
            app.PeterCorkePanel.Title = 'Peter Corke';
            app.SolveFKButton_2 = uibutton(app.PeterCorkePanel, 'push');
            app.SolveFKButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveFKButton_2Pushed, true);
            app.SolveFKButton_2.Text = 'Solve FK';
            app.SolveIKButton_2 = uibutton(app.PeterCorkePanel, 'push');
            app.SolveIKButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveIKButton_2Pushed, true);
            app.SolveIKButton_2.Text = 'Solve IK';
            app.XEditField_4Label = uilabel(app.PeterCorkePanel);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.YEditField_4Label = uilabel(app.PeterCorkePanel);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.ZEditField_4Label = uilabel(app.PeterCorkePanel);
            app.ZEditField_4Label.Text = 'Z';
            app.ZEditField_4 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.XEditField_2Label = uilabel(app.PeterCorkePanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.YEditField_2Label = uilabel(app.PeterCorkePanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.PeterCorkePanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.PeterCorkePanel, 'numeric');
            app.InverseKinematicsPanel = uipanel(app.GridLayout);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.SolveIKButton = uibutton(app.InverseKinematicsPanel, 'push');
            app.SolveIKButton.ButtonPushedFcn = createCallbackFcn(app, @SolveIKButtonPushed, true);
            app.SolveIKButton.Text = 'Solve IK';
            app.XEditField_3Label = uilabel(app.InverseKinematicsPanel);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.InverseKinematicsPanel);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.InverseKinematicsPanel);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.InverseKinematicsPanel, 'numeric');
            app.EnvelopeTab = uitab(app.TabGroup);
            app.EnvelopeTab.Title = 'Envelope';
            app.GridLayout2 = uigridlayout(app.EnvelopeTab);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Lamp = uilamp(app.GridLayout4);
            app.SwitchLabel = uilabel(app.GridLayout4);
            app.SwitchLabel.Text = 'Switch';
            app.WorkingSpaceSwitch = uiswitch(app.GridLayout4, 'slider');
            app.WorkingSpaceSwitch.ValueChangedFcn = createCallbackFcn(app, @WorkingSpaceSwitchValueChanged, true);
            app.GuideTab = uitab(app.TabGroup);
            app.GuideTab.Title = 'Guide';
            app.GridLayout3 = uigridlayout(app.GuideTab);
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.Enable = 'off';
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-180 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.Enable = 'off';
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-180 180];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.Enable = 'off';
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.Enable = 'off';
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.Enable = 'off';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint6Slider.Enable = 'off';
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.Limits = [-180 180];
            app.Joint7Slider.ValueChangingFcn = createCallbackFcn(app, @Joint7SliderValueChanging, true);
            app.Joint7Slider.Enable = 'off';
            app.Joint8SliderLabel = uilabel(app.UIFigure);
            app.Joint8SliderLabel.Text = 'Joint 8';
            app.Joint8Slider = uislider(app.UIFigure);
            app.Joint8Slider.Limits = [-180 180];
            app.Joint8Slider.ValueChangingFcn = createCallbackFcn(app, @Joint8SliderValueChanging, true);
            app.Joint8Slider.Enable = 'off';
            app.Joint9SliderLabel = uilabel(app.UIFigure);
            app.Joint9SliderLabel.Text = 'Joint 9';
            app.Joint9Slider = uislider(app.UIFigure);
            app.Joint9Slider.Limits = [-180 180];
            app.Joint9Slider.ValueChangingFcn = createCallbackFcn(app, @Joint9SliderValueChanging, true);
            app.Joint9Slider.Enable = 'off';
            app.Joint10SliderLabel = uilabel(app.UIFigure);
            app.Joint10SliderLabel.Text = 'Joint 10';
            app.Joint10Slider = uislider(app.UIFigure);
            app.Joint10Slider.Limits = [-180 180];
            app.Joint10Slider.ValueChangingFcn = createCallbackFcn(app, @Joint10SliderValueChanging, true);
            app.Joint10Slider.Enable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Enable = 'off';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Enable = 'off';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Enable = 'off';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Enable = 'off';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end